classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_4       matlab.ui.control.TextArea
        RsultatLabel     matlab.ui.control.Label
        TextArea_3       matlab.ui.control.TextArea
        EditField_2      matlab.ui.control.NumericEditField
        TextArea_2       matlab.ui.control.TextArea
        EditField        matlab.ui.control.NumericEditField
        TextArea         matlab.ui.control.TextArea
        CalculerButton   matlab.ui.control.Button
        GrandeurListBox  matlab.ui.control.ListBox
    end

    
   methods (Access = private)
    function result = calculateResult(app, grandeur, T, rho)
        
        load('constantes.mat','rhoc','Tc');
        delta =  rho./rhoc;
        tau = Tc./T;

    if exist(grandeur, 'file') == 2
        result = feval(grandeur, delta,tau);
    else
        error('La fonction spécifiée n''existe pas.');
    end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculerButton
        function CalculerButtonPushed(app, event)
            
        GrandeurLabel = findobj(app.UIFigure, 'Tag', 'grandeur');
        grandeur = GrandeurLabel.Value;
          
        % Récupérer la valeur de T en utilisant le tag
        TEditField = findobj(app.UIFigure, 'Tag', 'T');
        T = TEditField.Value;

        % Récupérer la valeur de rho en utilisant le tag
        rhoEditField = findobj(app.UIFigure, 'Tag', 'rho');
        rho = rhoEditField.Value;
        
        ResultatLabel = findobj(app.UIFigure, 'Tag', 'resultat');
        ResultatLabel.Text = [num2str(grandeur)+" : "+ num2str(calculateResult(app,grandeur,T,rho))+ " (SI)"];
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GrandeurListBox = uilistbox(app.UIFigure);
            app.GrandeurListBox.Items = {'u', 'h', 's', 'cv', 'cp', 'p'};
            app.GrandeurListBox.Tag = 'grandeur';
            app.GrandeurListBox.Value = 'h';
            app.CalculerButton = uibutton(app.UIFigure, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed, true);
            app.CalculerButton.Text = 'Calculer';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Veuillez selectionner la grandeur à calculer et rentrer les valeurs de T et rho :'};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Tag = 'T';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'T (en K)'};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Tag = 'rho';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'rho (kg/m3)'};
            app.RsultatLabel = uilabel(app.UIFigure);
            app.RsultatLabel.Tag = 'resultat';
            app.RsultatLabel.Text = 'Résultat :';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Calcul des Données Thermodynamique du CO2'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end